classdef Frame2_2vejrtraekninger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Introtekst2vejrtraekningerLabel  matlab.ui.control.Label
        AfbrydButton        matlab.ui.control.Button
        Image               matlab.ui.control.Image
        infoFrame2Button    matlab.ui.control.Button
        VejrtrkningregistreretEditFieldLabel  matlab.ui.control.Label
        VejrtrkningregistreretEditField  matlab.ui.control.NumericEditField
        FlotfortstterLabel  matlab.ui.control.Label
        PauseFrame12Button  matlab.ui.control.Button
        VejrtrkningButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        initFlow % simulated flows data matrix  recieved from previous window.
        initPressure % simulated pressures data matrix  recieved from previous window. 
        callingApp % Calling app reference
        newWindow % new window to open: live training plot
        
        thresholdTable = 0:5:105;
        
        RR;
        C;
        MIP;
        duration;
        threshold;
        sF;
        amount;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, RR, C, MIP, duration, threshold, sF, amount)
            app.callingApp = callingApp;
            
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            
            [app.initFlow, app.initPressure] = breathSimulation(RR,C,MIP,duration,threshold, sF); %Generating initiating flow and pressure


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: AfbrydButton
        function AfbrydButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: VejrtrkningButton
        function VejrtrkningButtonPushed(app, event)
            [peak loc] = findpeaks(app.initPressure); %Todo Add intelligens regarding this detection
            
            if(peak > 10)%Threshold for hvornår vejtrækningen registreres
                app.VejrtrkningregistreretEditField.Value = 1; %Indikerer at der har været en vejtrækning
                app.FlotfortstterLabel.Visible = 'on';
            end
            app.threshold = 0.7*peak;
            modstand = find(app.thresholdTable <= app.threshold,1,'last') %So as to always choose the lower setting
            
            pause(0.5);
            
            frame6_modstandsindstilling(app, app.initFlow, app.initPressure, modstand, app.RR,app.C,app.MIP,app.duration,app.threshold, app.sF, app.amount)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Introtekst2vejrtraekningerLabel = uilabel(app.UIFigure);
            app.Introtekst2vejrtraekningerLabel.Text = {'Tag en indånding, hvor du trækker '; 'vejret så dybt og hurtigt du kan.'; 'Start når du er klar!'; ''};
            app.AfbrydButton = uibutton(app.UIFigure, 'push');
            app.AfbrydButton.ButtonPushedFcn = createCallbackFcn(app, @AfbrydButtonPushed, true);
            app.AfbrydButton.Text = 'Afbryd';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Vejrtraekning.png';
            app.infoFrame2Button = uibutton(app.UIFigure, 'push');
            app.infoFrame2Button.Tooltip = {'Der er nu registreret 1 gennemført vejrtækning. '; 'Du mangler derfor  1 vejrtrækning mere før din anbefalede træningsmodstand er klar.'; ''; '1. Tag mundstykket op til munden'; '2. Træk vejret ind gennem træningsenheden og pust       derefter ud igen. Du skal trække så meget luft ind som du kan og hurtigst muligt.'; '3. Du vil efterfølgende få visr den anbefalede træningsmodstand'};
            app.infoFrame2Button.Text = 'Info';
            app.VejrtrkningregistreretEditFieldLabel = uilabel(app.UIFigure);
            app.VejrtrkningregistreretEditFieldLabel.Text = 'Vejrtrækning registreret:';
            app.VejrtrkningregistreretEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlotfortstterLabel = uilabel(app.UIFigure);
            app.FlotfortstterLabel.Visible = 'off';
            app.FlotfortstterLabel.Text = 'Flot! - fortsætter...';
            app.PauseFrame12Button = uibutton(app.UIFigure, 'push');
            app.PauseFrame12Button.Text = 'Spring modstansindstilling over';
            app.VejrtrkningButton = uibutton(app.UIFigure, 'push');
            app.VejrtrkningButton.ButtonPushedFcn = createCallbackFcn(app, @VejrtrkningButtonPushed, true);
            app.VejrtrkningButton.Text = 'Vejrtrækning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame2_2vejrtraekninger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end